classdef animator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Animator                 matlab.ui.Figure
        Label5                   matlab.ui.control.Label
        Panel4                   matlab.ui.container.Panel
        Label6                   matlab.ui.control.Label
        LineNumberSpinner        matlab.ui.control.Spinner
        Code                     matlab.ui.control.EditField
        FileName                 matlab.ui.control.Label
        Panel3                   matlab.ui.container.Panel
        FramesPerSecond          matlab.ui.control.NumericEditField
        FramesSecEditFieldLabel  matlab.ui.control.Label
        SaveAnimation            matlab.ui.control.CheckBox
        LabelEditField           matlab.ui.control.Label
        GifFileName              matlab.ui.control.EditField
        Panel2                   matlab.ui.container.Panel
        SweepRange               matlab.ui.control.EditField
        Label4                   matlab.ui.control.Label
        NumSteps                 matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        AnimationOption          matlab.ui.control.DropDown
        Reverse                  matlab.ui.control.CheckBox
        Connect                  matlab.ui.control.Button
        Animate                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CodeLine
    end
    
    methods (Access = private)
        
        function AnimateSweep(app)
            
            sweep( ...
                app.FileName.Text, ...
                app.CodeLine, ...
                app.SweepRange.Value, ...
                app.LineNumberSpinner.Value, ...
                app.NumSteps.Value, ...
                app.AnimationOption.Value, ...
                app.Reverse.Value, ...
                app.SaveAnimation.Value, ...
                app.GifFileName.Value, ...
                app.FramesPerSecond.Value);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = app.Animator;
            fig.HandleVisibility = 'off';
            fig.Resize = 'off';
            app.Animate.Enable = 'off';
            app.LineNumberSpinner.Enable = 'off';
            app.GifFileName.Enable = 'off';
            app.SaveAnimation.Value = false;
            
        end

        % Button pushed function: Connect
        function ConnectButtonPushed(app, event)
            
            % FIXME: need to work with new API.
            
            % Go to the Editor and find the active file
            hEditor = matlab.desktop.editor.getActive;
            
            [~,filename] = fileparts(hEditor.Filename);
            app.FileName.Text = filename;
            app.GifFileName.Value = [filename,'.gif'];
            SaveAnimationValueChanged(app)
            
            % Extract the current line
            selection = hEditor.Selection;
            lineNum = selection(1);
            app.LineNumberSpinner.Value = lineNum;
            
            pos1 = matlab.desktop.editor.positionInLineToIndex(hEditor,lineNum,1);
            pos2 = matlab.desktop.editor.positionInLineToIndex(hEditor,lineNum,10000);
            
            hEditor.goToLine(lineNum)
            txt = hEditor.Text;

            % Protect against either text position being zero
            pos1 = max(pos1,1);
            pos2 = max(pos2,1);

            % Protect against either text position being too big
            pos1 = min(pos1,length(txt));
            pos2 = min(pos2,length(txt));

            codeLine = txt(pos1:pos2);
            app.CodeLine = codeLine;
            [sweepVar, startVal, sweepRange] = splitCode(codeLine);
            
            if isempty(sweepVar)
                app.Animate.Enable = 'off';
                disp(' ')
                disp('This does not appear to be a variable assignment statement.')
                disp('  The code line should have this form:')
                disp('  <variable> = <numeric value>')
                disp('  For example: "x = 12"')
                disp(' ')
            else
                app.Animate.Enable = 'on';
                app.LineNumberSpinner.Enable = 'on';
            end
            
            codeLineNoComment = regexprep(codeLine,'\s*%.*$','');
            app.Code.Value = codeLineNoComment;
            
            % Don't bother setting up the Variable and Range fields
            % if the line is not an assignment
            if ~isempty(startVal)
                if isempty(sweepRange)
                    sweepRange = [startVal startVal];
                end
                rangeStr = sprintf('%s .. %s',num2str(sweepRange(1)), num2str(sweepRange(2)));
                app.SweepRange.Value = rangeStr;
            end
        end

        % Button pushed function: Animate
        function AnimateButtonPushed(app, event)
            
            AnimateSweep(app)
            
        end

        % Value changed function: SaveAnimation
        function SaveAnimationValueChanged(app, event)
            if app.SaveAnimation.Value
                val = 'on';
            else
                val = 'off';
            end
            app.GifFileName.Enable = val;

        end

        % Value changed function: LineNumberSpinner
        function LineNumberSpinnerValueChanged(app, event)
            
            hEditor = matlab.desktop.editor.getActive;
            lineNumber = app.LineNumberSpinner.Value;
            
            matlab.desktop.editor.positionInLineToIndex(hEditor,lineNumber,1);
            hEditor.goToLine(lineNumber)
            
            ConnectButtonPushed(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Animator = uifigure('Visible', 'off');
            app.Animator.Name = 'MATLAB Animator';
            app.Animate = uibutton(app.Animator, 'push');
            app.Animate.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.Animate.Text = 'Animate';
            app.Connect = uibutton(app.Animator, 'push');
            app.Connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.Connect.Text = 'Connect';
            app.Panel2 = uipanel(app.Animator);
            app.Panel2.Title = 'Motion';
            app.Reverse = uicheckbox(app.Panel2);
            app.Reverse.Text = 'Forward & Back';
            app.AnimationOption = uidropdown(app.Panel2);
            app.AnimationOption.Items = {'Sinusoid', 'Linear'};
            app.AnimationOption.ItemsData = [1 2];
            app.AnimationOption.Value = 1;
            app.Label = uilabel(app.Panel2);
            app.Label.Text = 'Number of Frames';
            app.NumSteps = uieditfield(app.Panel2, 'numeric');
            app.NumSteps.ValueDisplayFormat = '%.0f';
            app.NumSteps.Value = 60;
            app.Label4 = uilabel(app.Panel2);
            app.Label4.Text = 'Variable Range';
            app.SweepRange = uieditfield(app.Panel2, 'text');
            app.Panel3 = uipanel(app.Animator);
            app.Panel3.Title = 'File Output';
            app.GifFileName = uieditfield(app.Panel3, 'text');
            app.LabelEditField = uilabel(app.Panel3);
            app.LabelEditField.Text = 'GIF File Name';
            app.SaveAnimation = uicheckbox(app.Panel3);
            app.SaveAnimation.ValueChangedFcn = createCallbackFcn(app, @SaveAnimationValueChanged, true);
            app.SaveAnimation.Text = 'Save GIF File';
            app.FramesSecEditFieldLabel = uilabel(app.Panel3);
            app.FramesSecEditFieldLabel.Text = 'Frames/Sec';
            app.FramesPerSecond = uieditfield(app.Panel3, 'numeric');
            app.FramesPerSecond.Value = 24;
            app.Panel4 = uipanel(app.Animator);
            app.Panel4.Title = 'Code';
            app.FileName = uilabel(app.Panel4);
            app.FileName.Text = '';
            app.Code = uieditfield(app.Panel4, 'text');
            app.Code.Editable = 'off';
            app.LineNumberSpinner = uispinner(app.Panel4);
            app.LineNumberSpinner.Limits = [1 Inf];
            app.LineNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @LineNumberSpinnerValueChanged, true);
            app.LineNumberSpinner.Value = 1;
            app.Label6 = uilabel(app.Panel4);
            app.Label6.Text = 'Line No.';
            app.Label5 = uilabel(app.Animator);
            app.Label5.Text = 'Animator';
            % Show the figure after all components are created
            app.Animator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Animator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Animator)
        end
    end
end